classdef main_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        PulseSettingsTab               matlab.ui.container.Tab
        LaserVoltageVEditFieldLabel    matlab.ui.control.Label
        LaserVoltageVEditField         matlab.ui.control.NumericEditField
        PulseWidthnsEditFieldLabel     matlab.ui.control.Label
        PulseWidthnsEditField          matlab.ui.control.NumericEditField
        DelaysLabel                    matlab.ui.control.Label
        DelaysEditField                matlab.ui.control.NumericEditField
        EditField                      matlab.ui.control.NumericEditField
        EditField_2                    matlab.ui.control.NumericEditField
        EditField_3                    matlab.ui.control.NumericEditField
        AmorphizationLabel             matlab.ui.control.Label
        CrystallizationLabel           matlab.ui.control.Label
        DutyCycleLabel                 matlab.ui.control.Label
        DutyCycleEditField             matlab.ui.control.NumericEditField
        FrequencykHzEditFieldLabel     matlab.ui.control.Label
        FrequencykHzEditField          matlab.ui.control.NumericEditField
        DutyCycleEditField_2           matlab.ui.control.NumericEditField
        ConfirmButton                  matlab.ui.control.StateButton
        PeriodsEditFieldLabel          matlab.ui.control.Label
        PeriodsEditField               matlab.ui.control.NumericEditField
        Image                          matlab.ui.control.Image
        EditField_46                   matlab.ui.control.NumericEditField
        EditField_47                   matlab.ui.control.NumericEditField
        EditField_48                   matlab.ui.control.NumericEditField
        EditField_49                   matlab.ui.control.NumericEditField
        EditField_50                   matlab.ui.control.NumericEditField
        DutyCycleEditField_3           matlab.ui.control.NumericEditField
        EditField_51                   matlab.ui.control.NumericEditField
        EditField_52                   matlab.ui.control.NumericEditField
        MonitoringTab                  matlab.ui.container.Tab
        ControllerDropDownLabel        matlab.ui.control.Label
        ControllerDropDown             matlab.ui.control.DropDown
        DCDCConverterDropDownLabel     matlab.ui.control.Label
        DCDCConverterDropDown          matlab.ui.control.DropDown
        PowerConsumptionWGaugeLabel    matlab.ui.control.Label
        PowerConsumptionWGauge         matlab.ui.control.SemicircularGauge
        HostnameEditFieldLabel         matlab.ui.control.Label
        HostnameEditField              matlab.ui.control.EditField
        RaspberryPiLabel               matlab.ui.control.Label
        UsernameEditFieldLabel         matlab.ui.control.Label
        UsernameEditField              matlab.ui.control.EditField
        IPAddressEditFieldLabel        matlab.ui.control.Label
        IPAddressEditField             matlab.ui.control.EditField
        CameraResolutionDropDownLabel  matlab.ui.control.Label
        CameraResolutionDropDown       matlab.ui.control.DropDown
        TestRaspberryPiCameraButton    matlab.ui.control.Button
        EnvironmentTab                 matlab.ui.container.Tab
        AmbientTemperatureCLabel       matlab.ui.control.Label
        AmbientTemperatureCGauge       matlab.ui.control.LinearGauge
        HumidityGaugeLabel             matlab.ui.control.Label
        HumidityGauge                  matlab.ui.control.LinearGauge
        PressurepsiGaugeLabel          matlab.ui.control.Label
        PressurepsiGauge               matlab.ui.control.LinearGauge
        Instruments                    matlab.ui.container.Tab
        ResourceDropDownLabel          matlab.ui.control.Label
        ResourceDropDown               matlab.ui.control.DropDown
        DriverDropDownLabel            matlab.ui.control.Label
        DriverDropDown                 matlab.ui.control.DropDown
        ConnectButton                  matlab.ui.control.Button
        DisconnectButton               matlab.ui.control.Button
        ChannelDropDownLabel           matlab.ui.control.Label
        ChannelDropDown                matlab.ui.control.DropDown
        VerticalRangeVEditFieldLabel   matlab.ui.control.Label
        VerticalRangeEditField         matlab.ui.control.NumericEditField
        AquisitionTimesEditFieldLabel  matlab.ui.control.Label
        AcquisitionTimeEditField       matlab.ui.control.NumericEditField
        AutoSetupButton                matlab.ui.control.StateButton
        ResourceScanButton             matlab.ui.control.Button
        VerticalCouplingDropDownLabel  matlab.ui.control.Label
        VerticalCouplingDropDown       matlab.ui.control.DropDown
        OscilloscopeLabel              matlab.ui.control.Label
        AutoSetupButton_2              matlab.ui.control.StateButton
        ResourceScanButton_2           matlab.ui.control.Button
        FunctionGeneratorLabel         matlab.ui.control.Label
        ResourceDropDown_2Label        matlab.ui.control.Label
        ResourceDropDown_2             matlab.ui.control.DropDown
        DriverDropDown_2Label          matlab.ui.control.Label
        DriverDropDown_2               matlab.ui.control.DropDown
        ChannelDropDown_2Label         matlab.ui.control.Label
        ChannelDropDown_2              matlab.ui.control.DropDown
        VerticalCouplingDropDown_2Label  matlab.ui.control.Label
        VerticalCouplingDropDown_2     matlab.ui.control.DropDown
        ConnectButton_2                matlab.ui.control.Button
        DisconnectButton_2             matlab.ui.control.Button
        ResourceDropDown_3Label        matlab.ui.control.Label
        ResourceDropDown_3             matlab.ui.control.DropDown
        USBRelayLabel                  matlab.ui.control.Label
        AutomatedTunableFilterCharacterizationLabel  matlab.ui.control.Label
        TabGroup2                      matlab.ui.container.TabGroup
        MeasurementModeTab             matlab.ui.container.Tab
        TotalCyclesEditFieldLabel      matlab.ui.control.Label
        TotalCyclesEditField           matlab.ui.control.NumericEditField
        TotalTimeminEditFieldLabel     matlab.ui.control.Label
        TotalTimeminEditField          matlab.ui.control.NumericEditField
        SaveeveryEditFieldLabel        matlab.ui.control.Label
        SaveeveryEditField             matlab.ui.control.NumericEditField
        cyclesLabel                    matlab.ui.control.Label
        SaveeveryEditField_2Label      matlab.ui.control.Label
        SaveeveryEditField_2           matlab.ui.control.NumericEditField
        minutesLabel                   matlab.ui.control.Label
        LimittheButtonGroup            matlab.ui.container.ButtonGroup
        totalcyclesButton              matlab.ui.control.RadioButton
        totaltimeButton                matlab.ui.control.RadioButton
        bothButton                     matlab.ui.control.RadioButton
        StopConditionTab               matlab.ui.container.Tab
        ofoccurancesoutsideoflimitCheckBox  matlab.ui.control.CheckBox
        NumberofoccurancesoutsideoflimitCheckBox  matlab.ui.control.CheckBox
        AmountoutsideoflimitCheckBox   matlab.ui.control.CheckBox
        Label                          matlab.ui.control.Label
        EditField_4                    matlab.ui.control.NumericEditField
        EditField_4Label_2             matlab.ui.control.Label
        EditField_5                    matlab.ui.control.NumericEditField
        EditField_6                    matlab.ui.control.NumericEditField
        DropDown                       matlab.ui.control.DropDown
        TransmittanceCheckBox          matlab.ui.control.CheckBox
        CenterWavelengthCheckBox       matlab.ui.control.CheckBox
        FWHMCheckBox                   matlab.ui.control.CheckBox
        StopbasedonLabel               matlab.ui.control.Label
        UpperLimitEditFieldLabel       matlab.ui.control.Label
        UpperLimitEditField            matlab.ui.control.NumericEditField
        LowerLimitEditFieldLabel       matlab.ui.control.Label
        LowerLimitEditField            matlab.ui.control.NumericEditField
        LimitLabel                     matlab.ui.control.Label
        StatisticsTab                  matlab.ui.container.Tab
        GridLayout2_2                  matlab.ui.container.GridLayout
        AmorphousTransmittanceLabel    matlab.ui.control.Label
        CrystallineTransmittanceLabel  matlab.ui.control.Label
        EditField_34                   matlab.ui.control.NumericEditField
        EditField_36                   matlab.ui.control.NumericEditField
        EditField_37                   matlab.ui.control.NumericEditField
        EditField_38                   matlab.ui.control.NumericEditField
        EditField_39                   matlab.ui.control.NumericEditField
        EditField_40                   matlab.ui.control.NumericEditField
        EditField_42                   matlab.ui.control.NumericEditField
        EditField_43                   matlab.ui.control.NumericEditField
        EditField_44                   matlab.ui.control.NumericEditField
        EditField_45                   matlab.ui.control.NumericEditField
        MeasuredLabel_2                matlab.ui.control.Label
        AverageLabel_3                 matlab.ui.control.Label
        AverageLabel_4                 matlab.ui.control.Label
        DeviationLabel_2               matlab.ui.control.Label
        MaximumLabel                   matlab.ui.control.Label
        MinimumLabel                   matlab.ui.control.Label
        AmorphousCWLLabel              matlab.ui.control.Label
        CrystallineCWLLabel            matlab.ui.control.Label
        EditField_54Label              matlab.ui.control.Label
        EditField_54                   matlab.ui.control.NumericEditField
        EditField2                     matlab.ui.control.NumericEditField
        EditField2_2                   matlab.ui.control.NumericEditField
        EditField2_3                   matlab.ui.control.NumericEditField
        EditField2_4Label              matlab.ui.control.Label
        EditField2_4                   matlab.ui.control.NumericEditField
        EditField3                     matlab.ui.control.NumericEditField
        EditField3_2                   matlab.ui.control.NumericEditField
        EditField3_3                   matlab.ui.control.NumericEditField
        TestingTab                     matlab.ui.container.Tab
        USBRelayPanel                  matlab.ui.container.Panel
        Switch                         matlab.ui.control.ToggleSwitch
        CurrentValuesPanel             matlab.ui.container.Panel
        TransmittanceLabel             matlab.ui.control.Label
        TransmittanceEditField         matlab.ui.control.NumericEditField
        CenterWavelengthmEditFieldLabel  matlab.ui.control.Label
        CenterWavelengthmEditField     matlab.ui.control.NumericEditField
        FWHMnmEditFieldLabel           matlab.ui.control.Label
        FWHMnmEditField                matlab.ui.control.NumericEditField
        SampleDataPanel                matlab.ui.container.Panel
        LoadIRImageButton              matlab.ui.control.Button
        LoadFTIRdataButton             matlab.ui.control.Button
        FilesTab                       matlab.ui.container.Tab
        FoldertoSaveFilesButton        matlab.ui.control.StateButton
        EditField_53                   matlab.ui.control.EditField
        FTIRDataFolderEditField        matlab.ui.control.EditField
        FTIRFileNameEditField          matlab.ui.control.EditField
        SaveddatafilenameEditFieldLabel  matlab.ui.control.Label
        SaveddatafilenameEditField     matlab.ui.control.EditField
        FoldertoLoadFilesButton        matlab.ui.control.StateButton
        FoldertoLoadFilesButton_2      matlab.ui.control.StateButton
        IRCamDataFolderEditField       matlab.ui.control.EditField
        IRCamFileNameEditField         matlab.ui.control.EditField
        SaveddatafilenameEditFieldLabel_2  matlab.ui.control.Label
        SaveddatafilenameEditFieldLabel_3  matlab.ui.control.Label
        Switch_2                       matlab.ui.control.Switch
        DrawROIButton                  matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        TabGroup3                      matlab.ui.container.TabGroup
        TransmittanceTab               matlab.ui.container.Tab
        UIAxes2                        matlab.ui.control.UIAxes
        CenterWavelengthTab            matlab.ui.container.Tab
        UIAxes3                        matlab.ui.control.UIAxes
        FWHMTab                        matlab.ui.container.Tab
        UIAxes5                        matlab.ui.control.UIAxes
        FTIRTab                        matlab.ui.container.Tab
        UIAxes3_2                      matlab.ui.control.UIAxes
        TabGroup4                      matlab.ui.container.TabGroup
        LaserOscFGenTab                matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        LiveWaveformPlotSwitchLabel    matlab.ui.control.Label
        LiveWaveformPlotSwitch         matlab.ui.control.Switch
        RefreshPeriodsSpinnerLabel     matlab.ui.control.Label
        RefreshPeriodsSpinner          matlab.ui.control.Spinner
        ReadWaveformButton             matlab.ui.control.Button
        ReadWaveformStatus             matlab.ui.control.Label
        IRImageTab                     matlab.ui.container.Tab
        UIAxes4                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Property % Description
        folder
        mypi
        cam
        masks = load('roi.mat','mask_1','mask_2','mask_3','mask_4','mask_5','mask_6');
        roi = load('roi.mat','roi_1','roi_2','roi_3','roi_4','roi_5','roi_6');
        roi1
        roi2
        roi3
        roi4
        roi5
        roi6
        mask1
        mask2
        mask3
        mask4
        mask5
        mask6
        cycle
        ftir_file_nmbr % Numbering of the FTIR files
        ats_file_nmbr % number of the IR camera files
    end
    
    properties (Access = private)
        t1 % timer object for the FTIR
        t2 % timer object for the IR camera
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % For the oscilloscope
        Oscilloscope     % Quick-control oscilloscope object handle
        LivePlotTimer    % Waveform live plot timer object handle
    end
    
    methods (Access = private)
        
        function frame = import_ir_gui(app,filename)
            ir_folder = app.IRCamDataFolderEditField.Value;
            try
                v = FlirMovieReader([ir_folder,'\',filename, '_',num2str(app.ats_file_nmbr),'.ats']); % open file
                app.ats_file_nmbr = app.ats_file_nmbr + 1;
                
            catch
                v = FlirMovieReader([ir_folder,'\', filename, '_',num2str(app.ats_file_nmbr-1),'.ats']); % open file
                warning('Displaying last ATS file; may not be current');
               
            end
            [frame, ~] = step(v); delete(v);
            imshow(frame,'parent',app.UIAxes4);
            colormap(app.UIAxes4,'parula');
            caxis(app.UIAxes4,[min(frame,[],'all') max(frame,[],'all')]);
        end
        
        
        function ncd = relay_startup(~,com_nmbr)
            ncd=serialport(com_nmbr); % assign serial port object
            set(ncd, 'BaudRate', 115200); % set BaudRate to 9600
            set(ncd, 'Parity', 'none'); % set Parity Bit to None
            set(ncd, 'DataBits', 8); % set DataBits to 8
            set(ncd, 'StopBit', 1); % set StopBit to 1
            %display the properties of serial port object in MATLAB Window
            disp(get(ncd,{'Type','Name','Port','BaudRate','Parity','DataBits','StopBits'}));
            fopen(ncd);           % Open Serial Port Object
            fwrite(ncd,254);      % ProXR Header Byte
        end
        
        function relay_trigger(~,ncd)
            fwrite(ncd,254);      % ProXR Header Byte
            fwrite(ncd,108);      % Turn on Relay 1
            fwrite(ncd,1);        % in Relay Bank 1
            %disp(fread(ncd,1));   % Read Confirmation Data
            %disp('Relay Is On');  % Tell User Relay has Activated
            fwrite(ncd,100);      % Turn off Relay 1
            fwrite(ncd,1);        % in Relay Bank 1
            %disp(fread(ncd,1));   % Read Confirmation Data
            %disp('Relay Is OFF'); % Tell User Relay has Activated
            fclose(ncd);          % Close Serial Port Object
        end
        
        function timer_fcn(~, ~, event)
            event_type = event.Type;
            fprintf(event_type);
            
        end
        
        function [lambda0, lambda1, lambda2] = ftir_plotting(app,csv_file)
            ftir_folder = app.FTIRDataFolderEditField.Value;
            try
                A = readmatrix([ftir_folder,'\',csv_file,'_',num2str(app.ftir_file_nmbr),'.CSV']);
                app.ftir_file_nmbr = app.ftir_file_nmbr + 1;
            catch
                A = readmatrix([ftir_folder,'\',csv_file,'_',num2str(app.ftir_file_nmbr-1),'.CSV']);
            end
            lambda = A(:,1);
            T = A(:,2); % transmittance
            % Find the half max value.
            halfMax = (min(T) + max(T)) / 2;
            % Find where the data first drops below half the max.
            index1 = find(T >= halfMax, 1, 'first');
            % Find where the data last rises above half the max.
            index2 = find(T >= halfMax, 1, 'last');
            
            % Maximum transmittance
            maxT = max(T); minT = min(T);
            
            % Find the center wavelength
            lambda1 = lambda(index1); lambda2 = lambda(index2);
            lambda0 = 2*lambda1*lambda2/(lambda1 + lambda2); % center wavlength
            for j = 1:find(T == maxT)
                if T(j) > 0.1
                    a1 = lambda(j-1);
                    break
                end
            end
            for k = find(T == maxT) + 1: length(T)
                if T(k) <= 0.1
                    a2 = lambda(k);
                    break
                end
            end
            
            plot(app.UIAxes3_2, lambda, T,'k');
            hold(app.UIAxes3_2,'on')
            xlim(app.UIAxes3_2, [min(lambda), 4]);
            ylim(app.UIAxes3_2, [0 125])
            xlabel(app.UIAxes3_2, 'Wavelength ({\mu}m)');
            ylabel(app.UIAxes3_2, '% Transmittance');
            plot(app.UIAxes3_2, lambda(T == maxT),maxT,'or'); % peak transmittance
            plot(app.UIAxes3_2, [lambda0 lambda0], [minT maxT],'b'); % center wavelength
            plot(app.UIAxes3_2, [a1, a1], [0 maxT],'g'); % attenuation
            plot(app.UIAxes3_2, [a2, a2], [0 maxT],'g'); % attenuation
            % FWHM
            plot(app.UIAxes3_2, [lambda1 lambda1], [minT T(lambda == lambda1)],'m') % First half-max
            plot(app.UIAxes3_2, [lambda2 lambda2], [minT T(lambda == lambda2)],'m') % Second half-max
            legend(app.UIAxes3_2,...
                'Transmittance',['Peak Transmittance: ',num2str(maxT),'%'],...
                ['Center Wavlength: ', num2str(lambda0), ' {\mu}m'], 'Attenuation',...
                '','FWHM','','Location','northwest','NumColumns',2);
            hold(app.UIAxes3_2,'off');
        end
        
        function cw_fwhm_tr(app, lambda0, lambda1, lambda2,tr_fig,cl_fig,fwhm_fig)
            % Center wavelength, full-width half maximum, and transmittance
            % Plot the center wavelength calcualted by FTIR
            %cl_fig = plot(app.UIAxes3,floor(app.cycle./2),lambda0,'o');
            %cl_fig = plot(app.UIAxes3,app.cycle,lambda0,'o');
            %hold(app.UIAxes3, 'on');
            addpoints(cl_fig, app.cycle, lambda0);
            
            % Plot the FWHM calculated by the FTIR
            %fwhm_fig=plot(app.UIAxes5,app.cycle,lambda2-lambda1,'o');
            %hold(app.UIAxes5, 'on');
            addpoints(fwhm_fig, app.cycle, lambda2-lambda1);
            
            % Load the IR Camera Image
            frame = import_ir_gui(app,app.IRCamFileNameEditField.Value);
            
            % Calculate transmittance and plot it
            %----------------------------------------------------------
            % Plot the transmittance calcualted from IR camera.
            m1 = mean2(frame(app.masks.mask_1));
            % m2 = mean2(frame(mask2));
            m3 = (sum(frame(app.masks.mask_3),'all')+sum(frame(app.masks.mask_4),'all') + ...
                sum(frame(app.masks.mask_5),'all')+sum(frame(app.masks.mask_6),'all'))/...
                (numel(frame(app.masks.mask_3))+numel(frame(app.masks.mask_4)) + ...
                numel(frame(app.masks.mask_5)) +numel(frame(app.masks.mask_6)));
            tr1 = m1/m3*100; % transmittance of GST filter
            %tr2 = m2/m3; % transmittance of reference filter
            %tr_fig = plot(app.UIAxes2, app.cycle, tr1,'o');
            addpoints(tr_fig, app.cycle,tr1);
            %hold(app.UIAxes2, 'on');
            
%             if rem(app.cycle,2) == 0
%                 cl_fig.Color = 'red';
%                 fwhm_fig.Color = 'red';
%                 tr_fig.Color = 'red';
%             else
%                 cl_fig.Color = 'blue';
%                 fwhm_fig.Color = 'blue';
%                 tr_fig.Color = 'blue';
%             end
        end
        
        function disable_buttons(app)
            app.IRCamFileNameEditField.Enable = 'off';
            app.FTIRFileNameEditField.Enable = 'off';
            app.SaveddatafilenameEditField.Enable = 'off';
        end
        
        function oscilloscope_startup(app)
            %startupFcn Creates oscilloscope object and initializes app UI with available drivers and resources
        
            % Create quick-control oscilloscope object
            app.Oscilloscope = oscilloscope;
            
            % Get available oscilloscope drivers at start time
            app.DriverDropDown.Items = getDriverNames(app, app.Oscilloscope);
            
            % Get available oscilloscope resources at start time
            % (execute ResourceScanButton callback function)
            ResourceScanButtonPushed(app,[])
            
            % Disable quick-control oscilloscope warnings such as
            % 'The set value 6.00000e+00 is different from instrument value 5.00000e+00.'
            warning('off','instrument:qcinstrument:realValueIsDifferent')
            
            % Configure app UI controls to correspond to instrument "disconnected" state
            % (Connection will be attempted when Connect button is pushed)
            setAppControlsState(app, 'disconnected');    
        end
        
                function closeApp_Callback(app, ~, event)
        %closeApp_Callback Executes after "Close Confirm" dialog window
        %  "Close Confirm" dialog window is launched from AppCloseRequest.
            
            switch event.SelectedOption
                case 'OK'
                    
                    % Disconnect from oscilloscope instrument
                    % (Execute DisconnectButtonPushed callback function)
                    DisconnectButtonPushed(app,[])
                    
                    % Re-enable warnings which where enabled in startupFcn
                    warning('on','instrument:qcinstrument:realValueIsDifferent')
                    
                    delete(app)
                    
                case 'Cancel'
                    % Continue
            end
        end
        
        function TimerFcn_Callback(app,~,~)
        %TimerFcn_Callback Timer callback function which executes periodically
        %  When LivePlotTimer is running this function does periodic waveform live plot updates
            
            y = readWaveform(app.Oscilloscope)';
            acquisitionTime = app.Oscilloscope.AcquisitionTime;
            waveFormLength = app.Oscilloscope.WaveformLength;
            t = linspace(0, acquisitionTime, waveFormLength)';
            plot(app.UIAxes, t, y)
            xlim(app.UIAxes, [t(1) t(end)])
            drawnow
            
        end
        
        function setAppControlsState(app, state)
        %setAppControlsState Updates UI components per specified app state
        %  STATE can be 'scan', 'connected', or 'disconnected'
        
            switch state
                case 'scan'
                    app.ResourceDropDown.Items = {'Scanning resources...'};
                    app.LiveWaveformPlotSwitch.Value = 'Off';
                    app.ConnectButton.Enable = 'off';
                    app.DisconnectButton.Enable = 'off';
                    app.ResourceDropDown.Enable = 'on';
                    app.ResourceScanButton.Enable = 'off';
                    app.DriverDropDown.Enable = 'off';
                    app.LiveWaveformPlotSwitch.Enable = 'off';
                    app.ReadWaveformButton.Enable = 'off';
                    app.DisconnectButton.Enable = 'off';
                    app.AutoSetupButton.Enable = 'off';
                    app.ChannelDropDown.Enable = 'off';
                    app.VerticalCouplingDropDown.Enable = 'off';
                    app.VerticalRangeEditField.Enable = 'off';
                    app.AcquisitionTimeEditField.Enable = 'off';
                    app.RefreshPeriodsSpinner.Enable = 'off';
                    % app.WorkspacevariableEditField.Enable = 'off';
                    app.ReadWaveformStatus.Text = '';
                    drawnow
                case 'connected'
                    app.LiveWaveformPlotSwitch.Value = 'Off';
                    app.ConnectButton.Enable = 'off';
                    app.DisconnectButton.Enable = 'on';
                    app.ResourceDropDown.Enable = 'off';
                    app.ResourceScanButton.Enable = 'off';
                    app.DriverDropDown.Enable = 'off';
                    app.LiveWaveformPlotSwitch.Enable = 'on';
                    app.ReadWaveformButton.Enable = 'on';
                    app.DisconnectButton.Enable = 'on';
                    app.AutoSetupButton.Enable = 'on';
                    app.ChannelDropDown.Enable = 'on';
                    app.VerticalCouplingDropDown.Enable = 'on';
                    app.VerticalRangeEditField.Enable = 'on';
                    app.AcquisitionTimeEditField.Enable = 'on';
                    app.RefreshPeriodsSpinner.Enable = 'on';
                    %app.WorkspacevariableEditField.Enable = 'on';
                    app.ReadWaveformStatus.Text = '';
                    drawnow
                    cla(app.UIAxes)
                case 'disconnected'
                    app.LiveWaveformPlotSwitch.Value = 'Off';
                    app.ConnectButton.Enable = 'on';
                    app.DisconnectButton.Enable = 'off';
                    app.ResourceDropDown.Enable = 'on';
                    app.ResourceScanButton.Enable = 'on';
                    app.DriverDropDown.Enable = 'on';
                    app.LiveWaveformPlotSwitch.Enable = 'off';
                    app.ReadWaveformButton.Enable = 'off';
                    app.DisconnectButton.Enable = 'off';
                    app.AutoSetupButton.Enable = 'off';
                    app.ChannelDropDown.Enable = 'off';
                    app.VerticalCouplingDropDown.Enable = 'off';
                    app.VerticalRangeEditField.Enable = 'off';
                    app.AcquisitionTimeEditField.Enable = 'off';
                    app.RefreshPeriodsSpinner.Enable = 'off';
                    %app.WorkspacevariableEditField.Enable = 'off';
                    app.ReadWaveformStatus.Text = '';
                    drawnow
                case 'setup'
                    app.LivewaveFormPlotSwitch.Value = 'Off';
                    app.ConnectButton.Enable = 'off';
                    app.DisconnectButton.Enable = 'off';
                    app.ResourceDropDown.Enable = 'off';
                    app.ResourceScanButton.Enable = 'off';
                    app.DriverDropDown.Enable = 'off';
                    app.LiveWaveformPlotSwitch.Enable = 'off';
                    app.ReadWaveformButton.Enable = 'off';
                    app.DisconnectButton.Enable = 'off';
                    app.AutoSetupButton.Enable = 'off';
                    app.ChannelDropDown.Enable = 'off';
                    app.VerticalCouplingDropDown.Enable = 'off';
                    app.VerticalRangeEditField.Enable = 'off';
                    app.AcquisitionTimeEditField.Enable = 'off';
                    app.RefreshPeriodsSpinner.Enable = 'off';
                    %app.WorkspacevariableEditField.Enable = 'off';
                    app.ReadWaveformStatus.Text = '';
                    drawnow
            end
            
        end
        
        function results = getDriverNames(~, o)
        %getDriverNames Returns cell array of driver names
        %  Input O is a quick-control oscilloscope object
            
            driverStruct = drivers(o);
            n = numel(driverStruct);
            driverNames = strings(n,1);
            for ii = 1:n
                driverNames(ii) = driverStruct{ii}.Name;
            end
            results = cellstr(driverNames);
        end

        function results = getResourceNames(~, o)
        %getResourceNames Returns cell array of resource names
        %  Input O is a quick-control oscilloscope object
            results = resources(o);
        end
        
        function TimerErrorFcn_Callback(app, ~, ~)
        %TimerErrorFcn_Callback Executes on error event of timer object
        
            app.LiveWaveformPlotSwitch.Value = 'Off';
            mymessage = {'Cannot keep up with periodic waveform reads.', 'Increase live waveform plot refresh period.'};
            uialert(app.OscilloscopeAppUIFigure, mymessage, 'Waveform read error');
        end
        
        function startLivePlot(app)
        %startLivePlot Starts waveform live plot
        %  Creates a timer object which periodically reads a waveform and updates the live plot
        
            % Stop live plot (if already running)
            stopLivePlot(app)
            
            % Create and configure timer for waveform live plot
            % When LivePlotTimer is running TimerFcn callback will do periodic waveform live plot updates
            app.LivePlotTimer = timer;
            app.LivePlotTimer.TimerFcn = @(obj,event) TimerFcn_Callback(app, obj, event);
            app.LivePlotTimer.Period = app.RefreshPeriodsSpinner.Value;
            app.LivePlotTimer.ExecutionMode = 'fixedRate';
            app.LivePlotTimer.BusyMode = 'error';
            app.LivePlotTimer.TasksToExecute = inf;
            app.LivePlotTimer.StartDelay = 1;
            app.LivePlotTimer.ErrorFcn = @(obj,event) TimerErrorFcn_Callback(app, obj, event);
            start(app.LivePlotTimer)
        end
        
        function stopLivePlot(app)
        %stopLivePlot Stops waveform live plot
        
            if ~isempty(app.LivePlotTimer)
                stop(app.LivePlotTimer)
                delete(app.LivePlotTimer)
                app.LivePlotTimer = [];
            end
        end
        
        function [channelProperties,  channelPropertiesValid] = selectChannel(~, o, channel)
        %selectChannel Selects only specified CHANNEL of oscilloscope object O and disable all other channels
        %  Returns specified channel's VerticalCoupling and VerticalRange property values
        
            disableChannel(o, o.ChannelNames);
            enableChannel(o, channel);
            
            try
                channelProperties.VerticalCoupling = configureChannel(o, channel, 'VerticalCoupling');
                channelProperties.VerticalRange = configureChannel(o, channel, 'VerticalRange');
                channelPropertiesValid = true;
            catch
                channelProperties.VerticalCoupling = '';
                channelProperties.VerticalRange = '';
                channelPropertiesValid = false;
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            app.cycle = 1;
            set(0,'defaultAxesFontSize',14);
            
            % For the files and folders
            if(~isdeployed); cd(fileparts(which('main_gui'))); end
            
            %app.folder = pwd;
            %app.folder = convertCharsToStrings(app.folder);
            app.EditField_53.Value = 'saved_data';
            app.FTIRDataFolderEditField.Value = 'ftir_files';
            app.IRCamDataFolderEditField.Value= 'ats_files';
            
            app.SaveddatafilenameEditField.Value = 'test_data';
            app.IRCamFileNameEditField.Value = 'sample';
            app.FTIRFileNameEditField.Value = 'PCM-Filter';
            
            if ~(logical(exist(app.FTIRDataFolderEditField.Value,'file'))) 
                mkdir(app.FTIRDataFolderEditField.Value);
            end
            
            if ~(logical(exist(app.IRCamDataFolderEditField.Value, 'file'))) 
                mkdir(app.IRCamDataFolderEditField.Value);
            end
            
            if ~(logical(exist(app.EditField_53.Value, 'file'))) 
                mkdir(app.EditField_53.Value);
            end
            
            % For the USB Relay
            %serial_info = instrhwinfo('serial');
            %ports = serial_info.AvailableSerialPorts;
            ports = {'COM2'};
            app.ResourceDropDown_3.Items = ports;
            app.ftir_file_nmbr = 1; app.ats_file_nmbr = 1;
            
            % For the Oscilloscope
%             oscilloscope_startup(app);

            % Creating the timer object
            %             app.myTimer = timer('Period',2,...
            %                 'ExecutionMode', 'fixedSpacing', ...
            %                 'TasksToExecute', Inf);
            %             app.myTimer.TimerFcn = @timer_fcn;
            
            % Display one frame of the IR image
            ir_folder = app.IRCamDataFolderEditField.Value;
            filename = app.IRCamFileNameEditField.Value;
            v = FlirMovieReader([ir_folder,'\',filename, '_1.ats']); % open file
            [frame, ~] = step(v); delete(v);
            imshow(frame,'parent',app.UIAxes4);
            colormap(app.UIAxes4,'parula');
            caxis(app.UIAxes4,[min(frame,[],'all') max(frame,[],'all')]);
            
            % Display the ROIs
            h1 = images.roi.Polygon(app.UIAxes4);
            h2 = images.roi.Polygon(app.UIAxes4);
            h3 = images.roi.Polygon(app.UIAxes4);
            
            h1.Position = app.roi.roi_1.Position; 
            h2.Position = app.roi.roi_2.Position; 
            h3.Position = app.roi.roi_3.Position; 
            
            h1.Color = 'red';
            h2.Color = 'red';
            h3.Color = 'red';
            
            h1.Visible = 'on'; 
            h2.Visible = 'on'; 
            h3.Visible = 'on';
            
            h1.LineWidth = 0.5;
            h2.LineWidth = 0.5; 
            h3.LineWidth = 0.5;
            
            hold(app.UIAxes4,'on');
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % For the oscilloscope
            %startupFcn Creates oscilloscope object and initializes app UI with available drivers and resources
        
            % Create quick-control oscilloscope object
            app.Oscilloscope = oscilloscope;
            
            % Get available oscilloscope drivers at start time
            app.DriverDropDown.Items = getDriverNames(app, app.Oscilloscope);
            
            % Get available oscilloscope resources at start time
            % (execute ResourceScanButton callback function)
            ResourceScanButtonPushed(app,[])
            
            % Disable quick-control oscilloscope warnings such as
            % 'The set value 6.00000e+00 is different from instrument value 5.00000e+00.'
            warning('off','instrument:qcinstrument:realValueIsDifferent')
            
            % Configure app UI controls to correspond to instrument "disconnected" state
            % (Connection will be attempted when Connect button is pushed)
            setAppControlsState(app, 'disconnected');  
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {602, 602};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {466, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            disp(value)
            if value == "On"
                %ncd=serial('COM4'); % assign serial port object
                ncd=serialport('COM3'); % assign serial port object
                set(ncd, 'BaudRate', 115200); % set BaudRate to 9600
                set(ncd, 'Parity', 'none'); % set Parity Bit to None
                set(ncd, 'DataBits', 8); % set DataBits to 8
                set(ncd, 'StopBit', 1); % set StopBit to 1
                %display the properties of serial port object in MATLAB Window
                disp(get(ncd,{'Type','Name','Port','BaudRate','Parity','DataBits','StopBits'}));
                fopen(ncd);           % Open Serial Port Object
                fwrite(ncd,254);      % ProXR Header Byte
                fwrite(ncd,108);      % Turn on Relay 1
                fwrite(ncd,1);        % in Relay Bank 1
                disp(fread(ncd,1));   % Read Confirmation Data
                disp('Relay Is On');  % Tell User Relay has Activated
                fclose(ncd);          % Close Serial Port Object
            elseif value == "Off"
                %ncd=serial('COM4'); % assign serial port object
                ncd=serialport('COM4'); % assign serial port object
                set(ncd, 'BaudRate', 115200); % set BaudRate to 9600
                set(ncd, 'Parity', 'none'); % set Parity Bit to None
                set(ncd, 'DataBits', 8); % set DataBits to 8
                set(ncd, 'StopBit', 1); % set StopBit to 1
                %display the properties of serial port object in MATLAB Window
                disp(get(ncd,{'Type','Name','Port','BaudRate','Parity','DataBits','StopBits'}));
                fopen(ncd);           % Open Serial Port Object
                fwrite(ncd,254);      % ProXR Header Byte
                fwrite(ncd,100);      % Turn off Relay 1
                fwrite(ncd,1);        % in Relay Bank 1
                disp(fread(ncd,1));   % Read Confirmation Data
                disp('Relay Is OFF'); % Tell User Relay has Activated
                fclose(ncd);          % Close Serial Port Object
            end
        end

        % Value changed function: EditField_53, 
        % FoldertoSaveFilesButton
        function ChooseFileLocationButtonValueChanged(app, event)
            app.FoldertoSaveFilesButton.Value;
            app.folder = uigetdir;
            app.folder = convertCharsToStrings(app.folder);
            app.EditField_53.Value = app.folder;
        end

        % Value changed function: ConfirmButton
        function ConfirmButtonValueChanged(app, event)
            %value = app.ConfirmButton.Value;
            % The pulse width to change to amorphous state.
            pw_a = app.PulseWidthnsEditField.Value;
            % The wait time after the amorphization pulse.
            delay_a = app.DelaysEditField.Value;
            delay_a = delay_a*1000; % Convert microseconds to nanoseconds
            % The duty cycle
            %ds_a = app.DutyCycleEditField.Value; % duty cycle of amorphization
            app.DutyCycleEditField.Value = pw_a/(pw_a+delay_a)*100;
            
            % Cystallization
            pw_c = app.EditField_2.Value; % amorphization value
            delay_c = app.EditField_3.Value; % amorphization value
            delay_c = delay_c*1000; % Convert microseconds to nanoseconds
            %ds_c = app.DutyCycleEditField.Value; % duty cycle of amorphization
            app.DutyCycleEditField_2.Value = pw_c/(pw_c+delay_c)*100;
            
            % Read Pulse
            pw_r = app.EditField_49.Value;
            delay_r = app.EditField_50.Value*1000;
            
            % Add up the pulse width and delay to get the period
            % in nanoseconds. Multiply by 10^-9 to change the unit to
            % seconds. Then take the reciprocal to get the frequency in
            % Hz. Then multiply by 10^-3 to get frequency in kHz.
            % app.FrequencykHzEditField.Value= (10^-3)/(10^-9*(pw_a+pw_c+delay_a+delay_c));
            
            % Frequency - Amorphization
            app.FrequencykHzEditField.Value = (10^-3)/(10^-9*(pw_a+delay_a));
            
            % Period - Amorphization
            app.PeriodsEditField.Value = (10^-3)*(pw_a+delay_a);
            
            % Frequency - Crystallization
            app.EditField_46.Value = (10^-3)/(10^-9*(pw_c+delay_c));
            
            % Peiod - Crystallization
            app.EditField_47.Value = (10^-3)*(pw_c+delay_c);
            
            % Frequency - Read Pulse
            app.EditField_51.Value = (10^-3)/(10^-9*(pw_r+delay_r));
            
            % Period - Read Pulse
            app.EditField_52.Value = (10^-3)*(pw_r+delay_r);
            
            % Duty Cycle - Read Pulse
            app.DutyCycleEditField_3.Value = pw_r/(pw_r+delay_r)*100;
            
            
            % Add up the two pulse widths and two delays to get the period
            % in nanoseconds. Then multiply by 10^-3 to convert to
            % microseconds.
            % app.PeriodsEditField.Value = (10^-3)*(pw_a+pw_c+delay_a+delay_c);
        end

        % Callback function
        function ButtonGroupSelectionChanged(app, event)
            
        end

        % Button pushed function: TestRaspberryPiCameraButton
        function TestRaspberryPiCameraButtonPushed(app, event)
            % clear mypi;
            %         hostname = 'raspberrypi';
            %         username = 'pi';
            %         password = 'nasaLaRCD304';
            %         ipaddress = '169.254.253.170';
            app.mypi = raspi();%ipaddress,username,password) % Use this one.
            % mypi = raspi(hostname,username,password) % Not this one
            app.cam = cameraboard(app.mypi,'Resolution','640x480');
            for i = 1:100
                img = snapshot(app.cam);
                image(app.UIAxes4,img);
                drawnow;
            end
            cla(app.UIAxes4);
        end

        % Button pushed function: LoadFTIRdataButton
        function LoadFTIRdataButtonPushed(app, event)
            A = readmatrix('PCM-Filter.CSV');
            lambda = A(:,1);
            T = A(:,2); % transmittance
            
            % Find the half max value.
            halfMax = (min(T) + max(T)) / 2;
            % Find where the data first drops below half the max.
            index1 = find(T >= halfMax, 1, 'first');
            % Find where the data last rises above half the max.
            index2 = find(T >= halfMax, 1, 'last');
            %fwhm = index2-index1 + 1; % FWHM in indexes.
            % OR, if you have an x vector
            %fwhmx = lambda(index2) - lambda(index1);
            
            % Maximum transmittance
            maxT = max(T);
            minT = min(T);
            
            % Find the center wavelength
            lambda1 = lambda(index1); lambda2 = lambda(index2);
            lambda0 = 2*lambda1*lambda2/(lambda1 + lambda2); % center wavlength
            
            for i = 1:find(T == maxT)
                if T(i) > 0.1
                    a1 = lambda(i-1);
                    break
                end
            end
            for i = find(T == maxT) + 1: length(T)
                if T(i) <= 0.1
                    a2 = lambda(i);
                    break
                end
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Plotting
            plot(app.UIAxes3_2, lambda, T,'k');
            hold(app.UIAxes3_2,'on')
            xlim(app.UIAxes3_2, [min(A(:,1)), 4]);
            ylim(app.UIAxes3_2, [0 125])
            xlabel(app.UIAxes3_2, 'Wavelength ({\mu}m)');
            ylabel(app.UIAxes3_2, '% Transmittance');
            plot(app.UIAxes3_2, lambda(T == maxT),maxT,'or'); % peak transmittance
            plot(app.UIAxes3_2, [lambda0 lambda0], [minT maxT],'b'); % center wavelength
            plot(app.UIAxes3_2, [a1, a1], [0 maxT],'g'); % attenuation
            plot(app.UIAxes3_2, [a2, a2], [0 maxT],'g'); % attenuation
            % FWHM
            plot(app.UIAxes3_2, [lambda1 lambda1], [minT T(lambda == lambda1)],'m') % First half-max
            plot(app.UIAxes3_2, [lambda2 lambda2], [minT T(lambda == lambda2)],'m') % Second half-max
            
            legend(app.UIAxes3_2,...
                'Transmittance',['Peak Transmittance: ',num2str(maxT),'%'],...
                ['Center Wavlength: ', num2str(lambda0), ' {\mu}m'], 'Attenuation',...
                '','FWHM: 0.065 {\mu}m','','Location','northwest','NumColumns',2);
            
            %

        end

        % Selection changed function: LimittheButtonGroup
        function LimittheButtonGroupSelectionChanged(app, event)
            selectedButton = app.LimittheButtonGroup.SelectedObject;
            
            if selectedButton == app.totalcyclesButton
                app.TotalTimeminEditField.Enable = 'off';
                app.TotalTimeminEditField.Editable = 'off';
                app.SaveeveryEditField.Enable = 'off';
                app.SaveeveryEditField.Editable = 'off';
                
            elseif selectedButton == app.totaltimeButton
                app.TotalCyclesEditField.Enable = 'off';
                app.TotalCyclesEditField.Editable = 'off';
                app.SaveeveryEditField_2.Enable = 'off';
                app.SaveeveryEditField_2.Editable = 'off';
            end
        end

        % Button pushed function: DrawROIButton
        function DrawROIButtonPushed(app, event)
            ir_folder = app.IRCamDataFolderEditField.Value;
            filename = app.IRCamFileNameEditField.Value;
            try
                v = FlirMovieReader([ir_folder,'\',filename, '_',num2str(app.ats_file_nmbr),'.ats']); % open file
                app.ats_file_nmbr = app.ats_file_nmbr + 1;
            catch
                v = FlirMovieReader([ir_folder, filename, '_',num2str(app.ats_file_nmbr-1),'.ats']); % open file
                warning('Displaying last ATS file; may not be current');
            end
            [frame, ~] = step(v); % read the next frame
            figure(1);
            imshow(frame);
            colormap('parula');
            caxis([min(frame,[],'all') max(frame,[],'all')]);
            
            app.roi1 = images.roi.Polygon; 
            app.roi2 = images.roi.Polygon; 
            app.roi3 = images.roi.Polygon;
            
            app.roi1.Color = 'red';
            app.roi2.Color = 'red';
            app.roi3.Color = 'red';
             
            draw(app.roi1);
            draw(app.roi2);
            draw(app.roi3);
            
            app.mask1 = createMask(app.roi1);
            app.mask2 = createMask(app.roi2);
            app.mask3 = createMask(app.roi3);

            mask_1 = app.mask1; roi_1 = app.roi1;
            mask_2 = app.mask2; roi_2 = app.roi2;
            mask_3 = app.mask3; roi_3 = app.roi3;

            save('roi.mat','mask_1','mask_2','mask_3','roi_1','roi_2','roi_3');
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            % Disable the buttons that shouldn't be pressed while the
            % experiment is running.
            disable_buttons(app);
            
            % Get the COM port number for the USB Relay
            %com_nmbr = app.ResourceDropDown_3.Value;
            
            % Run some commmands to initialize the relay if it's connected.
            try
                ncd = relay_startup(app,'COM3');
            catch
                 warning('No USB Relay Device Detected.')
            end
            
            % Start tracking time.
            time1 = clock;
            app.cycle = 1; % initialize the number of cycles at 1
            total_cycles = app.TotalCyclesEditField.Value;
            total_time = app.TotalTimeminEditField.Value;
            
            % Define where to save the file
            save_file = app.SaveddatafilenameEditField.Value;
            save_fldr = app.EditField_53.Value;
            
            % For transmittance
            tr_fig = animatedline(app.UIAxes2,'MaximumNumPoints',100,'Marker','o');
            % For center wavelength
            cl_fig = animatedline(app.UIAxes3,'MaximumNumPoints',100,'Marker','o');
            
            % For FWHM
            fwhm_fig=animatedline(app.UIAxes5,'MaximumNumPoints',100,'Marker','o');
            
            while ~(strcmp(app.Switch_2.Value, 'Stop'))
                fprintf('cycles = %.5f \n',app.cycle)
                % Trigger the IR camera
                % Comment out if the relay is not connected.
%                 relay_trigger(app,ncd);
                
                % Calculate and plot the spectral data.
                [lambda0,lambda1,lambda2]=ftir_plotting(app,app.FTIRFileNameEditField.Value);
                cw_fwhm_tr(app,lambda0,lambda1,lambda2,tr_fig,cl_fig,fwhm_fig); % Import FTIR data
%                 if rem(app.cycle,5) == 0
                  drawnow
%                 end
                
                % Save the data
                save([save_fldr,'\',save_file,'_',num2str(app.cycle),'.mat']);
                if (app.cycle >= total_cycles) || ...
                        (etime(clock, time1) >= total_time*60)
                    break
                end
                app.cycle = app.cycle + 1;
            end
            try
                fclose(ncd);
            catch
                 lastwarn('No USB Relay Device Detected');
            end
            
            app.Switch_2.Value = 'Stop';
        end

        % Value changed function: FoldertoLoadFilesButton
        function FoldertoLoadFilesButtonValueChanged(app, event)
            try
                app.FTIRDataFolderEditField.Value=convertCharsToStrings(uigetdir);
            catch
                app.FTIRDataFolderEditField.Value= pwd;
            end
        end

        % Value changed function: FoldertoLoadFilesButton_2
        function FoldertoLoadFilesButton_2ValueChanged(app, event)
            try
                app.IRCamDataFolderEditField.Value = convertCharsToStrings(uigetdir);
            catch
                app.IRCamDataFolderEditField.Value = pwd;
            end
        end

        % Value changed function: ResourceDropDown
        function ResourceDropDownValueChanged(app, event)
            setAppControlsState(app,'scan')
            app.ResourceDropDown.Items = getResourceNames(app, app.Oscilloscope);
            setAppControlsState(app,'disconnected')
        end

        % Button pushed function: ResourceScanButton
        function ResourceScanButtonPushed(app, event)
            %ResourceScanButtonPushed Gets available oscilloscope resource names and updates UI
            setAppControlsState(app,'scan')
            app.ResourceDropDown.Items = getResourceNames(app, app.Oscilloscope);
            setAppControlsState(app,'disconnected')
        end

        % Value changed function: LiveWaveformPlotSwitch
        function LiveWaveformPlotSwitchValueChanged(app, event)
       %LiveWaveformPlotSwitchValueChanged Starts or stops waveform live plot
        
            value = app.LiveWaveformPlotSwitch.Value;
            switch value
                case 'On'
                    startLivePlot(app)
                case 'Off'
                    stopLivePlot(app)
            end
        end

        % Value changed function: RefreshPeriodsSpinner
        function RefreshPeriodsSpinnerValueChanged(app, event)
        %RefreshPeriodsSpinnerValueChanged Updates live plot period if live plot is running
        
            livePlotEnabled = strcmp(app.LiveWaveformPlotSwitch.Value, 'On');
            if livePlotEnabled
                % Change live plot period
                % Stop live plot and restart it with new period value
                stopLivePlot(app)
                startLivePlot(app)
            end
        end

        % Button pushed function: ReadWaveformButton
        function ReadWaveformButtonPushed(app, event)
             %ReadWaveformButtonPushed Reads and saves a single waveform from the oscilloscope instrument
        %  The acquired waveform data is saved to base workspace as a timetable.
        %  If live plot waveform mode is disabled also plot the saved waveform.
            
            channelName = genvarname(app.Oscilloscope.ChannelsEnabled{1});
        
            livePlotEnabled = strcmp(app.LiveWaveformPlotSwitch.Value, 'On');
            
            % Update status text
            app.ReadWaveformStatus.Text = 'Reading waveform...';
            drawnow
            
            % Temporarely stop waveform live read&plot as it can interfere with waveform read
            stopLivePlot(app)
            
            try
                % If live plot is stopped clear UIAxes in preparation for plotting
                if ~livePlotEnabled
                    cla(app.UIAxes)
                    drawnow
                end
                
                % Read waveform data from oscilloscope instrument
                y = readWaveform(app.Oscilloscope)';
                acquisitionTime = app.Oscilloscope.AcquisitionTime;
                waveFormLength = app.Oscilloscope.WaveformLength;
                
                % Create array of timestamp values
                t = linspace(0, acquisitionTime, waveFormLength)';
                
                % Create waveform data timetable and save to base workspace
                TT = timetable(seconds(t),y, 'VariableNames', {channelName});
                assignin('base', app.WorkspacevariableEditField.Value, TT);
                
                % Update status text
                app.ReadWaveformStatus.Text = 'Waveform saved to workspace.';
                drawnow
                
                if ~livePlotEnabled
                    % If live plot is stopped plot acquired waveform
                    plot(app.UIAxes, t, y)
                    xlim(app.UIAxes, [t(1) t(end)])
                    drawnow
                end
                
            catch exception
                % Update status text
                app.ReadWaveformStatus.Text = '';
                drawnow
                uialert(app.OscilloscopeAppUIFigure, exception.message, 'Waveform read error');
            end
            
            % Restart waveform live plot if it was running
            if livePlotEnabled
                startLivePlot(app)
            end
        end

        % Value changed function: AcquisitionTimeEditField
        function AcquisitionTimeEditFieldValueChanged(app, event)
             %AcquisitionTimeEditFieldValueChanged Sets new acquisition time value on oscilloscope instrument
        
            value = app.AcquisitionTimeEditField.Value;
            try
                app.Oscilloscope.AcquisitionTime = value;
                app.AcquisitionTimeEditField.Value = app.Oscilloscope.AcquisitionTime;
            catch exception
                uialert(app.OscilloscopeAppUIFigure, exception.message, 'Channel property error');
            end
            app.AcquisitionTimeEditField.Value = app.Oscilloscope.AcquisitionTime;
        end

        % Value changed function: VerticalRangeEditField
        function VerticalRangeEditFieldValueChanged(app, event)
         %VerticalRangeEditFieldValueChanged Sets new channel property value on oscilloscope instrument
        
            value = app.VerticalRangeEditField.Value;
            try
                configureChannel(app.Oscilloscope, app.ChannelDropDown.Value, 'VerticalRange', value);
            catch exception
                uialert(app.OscilloscopeAppUIFigure, exception.message, 'Channel property error');
            end
            app.VerticalRangeEditField.Value = configureChannel(app.Oscilloscope, app.ChannelDropDown.Value, 'VerticalRange');
        end

        % Value changed function: VerticalCouplingDropDown
        function VerticalCouplingDropDownValueChanged(app, event)
           %VerticalCouplingDropDown Sets new channel property value on oscilloscope instrument
        
            value = app.VerticalCouplingDropDown.Value;
            try
                configureChannel(app.Oscilloscope, app.ChannelDropDown.Value, 'VerticalCoupling', value);
            catch exception
                uialert(app.OscilloscopeAppUIFigure, exception.message, 'Channel property error');
            end
            app.VerticalCouplingDropDown.Value = configureChannel(app.Oscilloscope, app.ChannelDropDown.Value, 'VerticalCoupling');

        end

        % Value changed function: ChannelDropDown
        function ChannelDropDownValueChanged(app, event)
            %ChannelDropDownValueChanged Selects a new channel on oscilloscope instrument
            
            value = app.ChannelDropDown.Value;
            stopLivePlot(app)
            setAppControlsState(app, 'setup');
                        
            try
                [channelProperties, channelPropertiesValid] = selectChannel(app, app.Oscilloscope, value);
                if channelPropertiesValid
                    app.VerticalCouplingDropDown.Value = channelProperties.VerticalCoupling;
                    app.VerticalRangeEditField.Value = channelProperties.VerticalRange;
                end
            catch exception
                uialert(app.OscilloscopeAppUIFigure, exception.message, 'Channel error');
            end
            
            app.AcquisitionTimeEditField.Value = app.Oscilloscope.AcquisitionTime;
            
            setAppControlsState(app, 'connected');
            
            % Disable channel property values if invalid
            app.VerticalCouplingDropDown.Enable = channelPropertiesValid;
            app.VerticalRangeEditField.Enable = channelPropertiesValid;
        end

        % Value changed function: AutoSetupButton
        function AutoSetupButtonValueChanged(app, event)
            %AutoSetupButtonPushed Executes AUTOSET routine on oscilloscope instrument
            
            stopLivePlot(app)
            setAppControlsState(app, 'setup');
            autoSetup(app.Oscilloscope);
            
            % Update UI with new channel property values
            % Select the first of the enabled channels after autoset and disable the others
            channel = app.Oscilloscope.ChannelsEnabled{1};
            
            app.ChannelDropDown.Value = channel;
            ChannelDropDownValueChanged(app,[])
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            %DisconnectButtonPushed Disconnects from oscilloscope instrument

            % Stop waveform live plot updates         
            stopLivePlot(app)
        
            try
                disconnect(app.Oscilloscope);
            catch
                % Already disconnected
            end
            
            setAppControlsState(app, 'disconnected');
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            %ConnectButtonPushed Connects to oscilloscope instrument
        
            try
                setAppControlsState(app, 'setup');
                % Set selected resource and driver
                app.Oscilloscope.Resource = app.ResourceDropDown.Value;
                app.Oscilloscope.Driver = app.DriverDropDown.Value;
                connect(app.Oscilloscope);
            catch exception
                setAppControlsState(app, 'disconnected');
                mymessage = 'Verify that the Resource and Driver names you provided are valid.';
                
                uialert(app.OscilloscopeAppUIFigure, {exception.message, mymessage}, 'Connection error');
                return
            end
            
            % Initialize channel name drop down list with available channel names from oscilloscope instrument
            app.ChannelDropDown.Items = app.Oscilloscope.ChannelNames;
            
            % Configure selected channel
            % (Execute ChannelDropDownValueChanged callback function)
            ChannelDropDownValueChanged(app,[])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.PulseSettingsTab = uitab(app.TabGroup);
            app.PulseSettingsTab.Title = 'Pulse Settings';
            app.LaserVoltageVEditFieldLabel = uilabel(app.PulseSettingsTab);
            app.LaserVoltageVEditFieldLabel.Text = 'Laser Voltage (V)';
            app.LaserVoltageVEditField = uieditfield(app.PulseSettingsTab, 'numeric');
            app.LaserVoltageVEditField.Value = 10;
            app.PulseWidthnsEditFieldLabel = uilabel(app.PulseSettingsTab);
            app.PulseWidthnsEditFieldLabel.Text = 'Pulse Width (ns)';
            app.PulseWidthnsEditField = uieditfield(app.PulseSettingsTab, 'numeric');
            app.PulseWidthnsEditField.Limits = [20 100];
            app.PulseWidthnsEditField.Value = 50;
            app.DelaysLabel = uilabel(app.PulseSettingsTab);
            app.DelaysLabel.Text = 'Delay (μs)';
            app.DelaysEditField = uieditfield(app.PulseSettingsTab, 'numeric');
            app.DelaysEditField.Value = 100;
            app.EditField = uieditfield(app.PulseSettingsTab, 'numeric');
            app.EditField.Value = 4;
            app.EditField_2 = uieditfield(app.PulseSettingsTab, 'numeric');
            app.EditField_2.Limits = [20 100];
            app.EditField_2.Value = 100;
            app.EditField_3 = uieditfield(app.PulseSettingsTab, 'numeric');
            app.EditField_3.Value = 100;
            app.AmorphizationLabel = uilabel(app.PulseSettingsTab);
            app.AmorphizationLabel.Text = 'Amorphization';
            app.CrystallizationLabel = uilabel(app.PulseSettingsTab);
            app.CrystallizationLabel.Text = 'Crystallization';
            app.DutyCycleLabel = uilabel(app.PulseSettingsTab);
            app.DutyCycleLabel.Text = 'Duty Cycle (%)';
            app.DutyCycleEditField = uieditfield(app.PulseSettingsTab, 'numeric');
            app.DutyCycleEditField.Editable = 'off';
            app.FrequencykHzEditFieldLabel = uilabel(app.PulseSettingsTab);
            app.FrequencykHzEditFieldLabel.Text = 'Frequency (kHz)';
            app.FrequencykHzEditField = uieditfield(app.PulseSettingsTab, 'numeric');
            app.FrequencykHzEditField.Editable = 'off';
            app.DutyCycleEditField_2 = uieditfield(app.PulseSettingsTab, 'numeric');
            app.DutyCycleEditField_2.Editable = 'off';
            app.ConfirmButton = uibutton(app.PulseSettingsTab, 'state');
            app.ConfirmButton.ValueChangedFcn = createCallbackFcn(app, @ConfirmButtonValueChanged, true);
            app.ConfirmButton.Text = 'Confirm';
            app.PeriodsEditFieldLabel = uilabel(app.PulseSettingsTab);
            app.PeriodsEditFieldLabel.Text = 'Period (μs)';
            app.PeriodsEditField = uieditfield(app.PulseSettingsTab, 'numeric');
            app.PeriodsEditField.Editable = 'off';
            app.Image = uiimage(app.PulseSettingsTab);
            app.Image.ImageSource = 'example_phase_change_graph.png';
            app.EditField_46 = uieditfield(app.PulseSettingsTab, 'numeric');
            app.EditField_46.Editable = 'off';
            app.EditField_47 = uieditfield(app.PulseSettingsTab, 'numeric');
            app.EditField_47.Editable = 'off';
            app.EditField_48 = uieditfield(app.PulseSettingsTab, 'numeric');
            app.EditField_48.Visible = 'off';
            app.EditField_48.Value = 1;
            app.EditField_49 = uieditfield(app.PulseSettingsTab, 'numeric');
            app.EditField_49.Limits = [20 100];
            app.EditField_49.Visible = 'off';
            app.EditField_49.Value = 100;
            app.EditField_50 = uieditfield(app.PulseSettingsTab, 'numeric');
            app.EditField_50.Visible = 'off';
            app.EditField_50.Value = 100;
            app.DutyCycleEditField_3 = uieditfield(app.PulseSettingsTab, 'numeric');
            app.DutyCycleEditField_3.Editable = 'off';
            app.DutyCycleEditField_3.Visible = 'off';
            app.EditField_51 = uieditfield(app.PulseSettingsTab, 'numeric');
            app.EditField_51.Editable = 'off';
            app.EditField_51.Visible = 'off';
            app.EditField_52 = uieditfield(app.PulseSettingsTab, 'numeric');
            app.EditField_52.Editable = 'off';
            app.EditField_52.Visible = 'off';
            app.MonitoringTab = uitab(app.TabGroup);
            app.MonitoringTab.Title = 'Monitoring';
            app.ControllerDropDownLabel = uilabel(app.MonitoringTab);
            app.ControllerDropDownLabel.Text = 'Controller';
            app.ControllerDropDown = uidropdown(app.MonitoringTab);
            app.ControllerDropDown.Items = {'Disconnected', 'Connected', 'Not Applicable'};
            app.ControllerDropDown.Value = 'Connected';
            app.DCDCConverterDropDownLabel = uilabel(app.MonitoringTab);
            app.DCDCConverterDropDownLabel.Text = 'DC-DC Converter';
            app.DCDCConverterDropDown = uidropdown(app.MonitoringTab);
            app.DCDCConverterDropDown.Items = {'Normal Operation', 'Error', 'Not Available'};
            app.DCDCConverterDropDown.Value = 'Normal Operation';
            app.PowerConsumptionWGaugeLabel = uilabel(app.MonitoringTab);
            app.PowerConsumptionWGaugeLabel.Text = 'Power Consumption (W)';
            app.PowerConsumptionWGauge = uigauge(app.MonitoringTab, 'semicircular');
            app.HostnameEditFieldLabel = uilabel(app.MonitoringTab);
            app.HostnameEditFieldLabel.Text = 'Hostname';
            app.HostnameEditField = uieditfield(app.MonitoringTab, 'text');
            app.HostnameEditField.Editable = 'off';
            app.HostnameEditField.Value = 'raspberrypi';
            app.RaspberryPiLabel = uilabel(app.MonitoringTab);
            app.RaspberryPiLabel.Text = 'Raspberry Pi';
            app.UsernameEditFieldLabel = uilabel(app.MonitoringTab);
            app.UsernameEditFieldLabel.Text = 'Username';
            app.UsernameEditField = uieditfield(app.MonitoringTab, 'text');
            app.UsernameEditField.Editable = 'off';
            app.UsernameEditField.Value = 'pi';
            app.IPAddressEditFieldLabel = uilabel(app.MonitoringTab);
            app.IPAddressEditFieldLabel.Text = 'IP Address';
            app.IPAddressEditField = uieditfield(app.MonitoringTab, 'text');
            app.IPAddressEditField.Editable = 'off';
            app.IPAddressEditField.Value = '169.254.253.170';
            app.CameraResolutionDropDownLabel = uilabel(app.MonitoringTab);
            app.CameraResolutionDropDownLabel.Text = 'Camera Resolution';
            app.CameraResolutionDropDown = uidropdown(app.MonitoringTab);
            app.CameraResolutionDropDown.Items = {'160x120', '320x240', '640x480', '800x600', '1024x768', '1280x720', '1920x1080'};
            app.CameraResolutionDropDown.Value = '640x480';
            app.TestRaspberryPiCameraButton = uibutton(app.MonitoringTab, 'push');
            app.TestRaspberryPiCameraButton.ButtonPushedFcn = createCallbackFcn(app, @TestRaspberryPiCameraButtonPushed, true);
            app.TestRaspberryPiCameraButton.Text = 'Test Raspberry Pi Camera';
            app.EnvironmentTab = uitab(app.TabGroup);
            app.EnvironmentTab.Title = 'Environment';
            app.AmbientTemperatureCLabel = uilabel(app.EnvironmentTab);
            app.AmbientTemperatureCLabel.Text = 'Ambient Temperature (°C)';
            app.AmbientTemperatureCGauge = uigauge(app.EnvironmentTab, 'linear');
            app.HumidityGaugeLabel = uilabel(app.EnvironmentTab);
            app.HumidityGaugeLabel.Text = 'Humidity (%)';
            app.HumidityGauge = uigauge(app.EnvironmentTab, 'linear');
            app.PressurepsiGaugeLabel = uilabel(app.EnvironmentTab);
            app.PressurepsiGaugeLabel.Text = 'Pressure (psi)';
            app.PressurepsiGauge = uigauge(app.EnvironmentTab, 'linear');
            app.Instruments = uitab(app.TabGroup);
            app.Instruments.Title = 'Instruments';
            app.ResourceDropDownLabel = uilabel(app.Instruments);
            app.ResourceDropDownLabel.Text = 'Resource';
            app.ResourceDropDown = uidropdown(app.Instruments);
            app.ResourceDropDown.ValueChangedFcn = createCallbackFcn(app, @ResourceDropDownValueChanged, true);
            app.DriverDropDownLabel = uilabel(app.Instruments);
            app.DriverDropDownLabel.Text = 'Driver';
            app.DriverDropDown = uidropdown(app.Instruments);
            app.ConnectButton = uibutton(app.Instruments, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.Instruments, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.ChannelDropDownLabel = uilabel(app.Instruments);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.Instruments);
            app.ChannelDropDown.Items = {'CH1'};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.ChannelDropDown.Value = 'CH1';
            app.VerticalRangeVEditFieldLabel = uilabel(app.Instruments);
            app.VerticalRangeVEditFieldLabel.Text = 'Vertical Range (V)';
            app.VerticalRangeEditField = uieditfield(app.Instruments, 'numeric');
            app.VerticalRangeEditField.ValueChangedFcn = createCallbackFcn(app, @VerticalRangeEditFieldValueChanged, true);
            app.AquisitionTimesEditFieldLabel = uilabel(app.Instruments);
            app.AquisitionTimesEditFieldLabel.Text = 'Aquisition Time (s)';
            app.AcquisitionTimeEditField = uieditfield(app.Instruments, 'numeric');
            app.AcquisitionTimeEditField.ValueChangedFcn = createCallbackFcn(app, @AcquisitionTimeEditFieldValueChanged, true);
            app.AutoSetupButton = uibutton(app.Instruments, 'state');
            app.AutoSetupButton.ValueChangedFcn = createCallbackFcn(app, @AutoSetupButtonValueChanged, true);
            app.AutoSetupButton.Text = 'Auto Setup';
            app.ResourceScanButton = uibutton(app.Instruments, 'push');
            app.ResourceScanButton.ButtonPushedFcn = createCallbackFcn(app, @ResourceScanButtonPushed, true);
            app.ResourceScanButton.Text = 'Scan';
            app.VerticalCouplingDropDownLabel = uilabel(app.Instruments);
            app.VerticalCouplingDropDownLabel.Text = 'Vertical Coupling';
            app.VerticalCouplingDropDown = uidropdown(app.Instruments);
            app.VerticalCouplingDropDown.Items = {'AC', 'DC', 'GND'};
            app.VerticalCouplingDropDown.ValueChangedFcn = createCallbackFcn(app, @VerticalCouplingDropDownValueChanged, true);
            app.VerticalCouplingDropDown.Value = 'AC';
            app.OscilloscopeLabel = uilabel(app.Instruments);
            app.OscilloscopeLabel.Text = 'Oscilloscope';
            app.AutoSetupButton_2 = uibutton(app.Instruments, 'state');
            app.AutoSetupButton_2.Enable = 'off';
            app.AutoSetupButton_2.Text = 'Auto Setup';
            app.ResourceScanButton_2 = uibutton(app.Instruments, 'push');
            app.ResourceScanButton_2.Text = 'Scan';
            app.FunctionGeneratorLabel = uilabel(app.Instruments);
            app.FunctionGeneratorLabel.Text = 'Function Generator';
            app.ResourceDropDown_2Label = uilabel(app.Instruments);
            app.ResourceDropDown_2Label.Text = 'Resource';
            app.ResourceDropDown_2 = uidropdown(app.Instruments);
            app.ResourceDropDown_2.Items = {'ASRL:COM4', 'Option 2', 'Option 3', 'Option 4'};
            app.ResourceDropDown_2.Value = 'ASRL:COM4';
            app.DriverDropDown_2Label = uilabel(app.Instruments);
            app.DriverDropDown_2Label.Text = 'Driver';
            app.DriverDropDown_2 = uidropdown(app.Instruments);
            app.DriverDropDown_2.Items = {'tektronix', 'Option 2', 'Option 3', 'Option 4'};
            app.DriverDropDown_2.Value = 'tektronix';
            app.ChannelDropDown_2Label = uilabel(app.Instruments);
            app.ChannelDropDown_2Label.Enable = 'off';
            app.ChannelDropDown_2Label.Text = 'Channel';
            app.ChannelDropDown_2 = uidropdown(app.Instruments);
            app.ChannelDropDown_2.Items = {'CH1'};
            app.ChannelDropDown_2.Enable = 'off';
            app.ChannelDropDown_2.Value = 'CH1';
            app.VerticalCouplingDropDown_2Label = uilabel(app.Instruments);
            app.VerticalCouplingDropDown_2Label.Enable = 'off';
            app.VerticalCouplingDropDown_2Label.Text = 'Vertical Coupling';
            app.VerticalCouplingDropDown_2 = uidropdown(app.Instruments);
            app.VerticalCouplingDropDown_2.Items = {'AC', 'DC', 'GND'};
            app.VerticalCouplingDropDown_2.Enable = 'off';
            app.VerticalCouplingDropDown_2.Value = 'AC';
            app.ConnectButton_2 = uibutton(app.Instruments, 'push');
            app.ConnectButton_2.Text = 'Connect';
            app.DisconnectButton_2 = uibutton(app.Instruments, 'push');
            app.DisconnectButton_2.Enable = 'off';
            app.DisconnectButton_2.Text = 'Disconnect';
            app.ResourceDropDown_3Label = uilabel(app.Instruments);
            app.ResourceDropDown_3Label.Text = 'Resource';
            app.ResourceDropDown_3 = uidropdown(app.Instruments);
            app.ResourceDropDown_3.Items = {};
            app.ResourceDropDown_3.Value = {};
            app.USBRelayLabel = uilabel(app.Instruments);
            app.USBRelayLabel.Text = 'USB Relay';
            app.AutomatedTunableFilterCharacterizationLabel = uilabel(app.LeftPanel);
            app.AutomatedTunableFilterCharacterizationLabel.Text = 'Automated Tunable Filter Characterization';
            app.TabGroup2 = uitabgroup(app.LeftPanel);
            app.MeasurementModeTab = uitab(app.TabGroup2);
            app.MeasurementModeTab.Title = 'Measurement Mode';
            app.TotalCyclesEditFieldLabel = uilabel(app.MeasurementModeTab);
            app.TotalCyclesEditFieldLabel.Text = 'Total Cycles';
            app.TotalCyclesEditField = uieditfield(app.MeasurementModeTab, 'numeric');
            app.TotalCyclesEditField.Value = 20;
            app.TotalTimeminEditFieldLabel = uilabel(app.MeasurementModeTab);
            app.TotalTimeminEditFieldLabel.Text = 'Total Time (min)';
            app.TotalTimeminEditField = uieditfield(app.MeasurementModeTab, 'numeric');
            app.TotalTimeminEditField.Value = 100;
            app.SaveeveryEditFieldLabel = uilabel(app.MeasurementModeTab);
            app.SaveeveryEditFieldLabel.Text = 'Save every';
            app.SaveeveryEditField = uieditfield(app.MeasurementModeTab, 'numeric');
            app.SaveeveryEditField.Value = 1;
            app.cyclesLabel = uilabel(app.MeasurementModeTab);
            app.cyclesLabel.Text = 'cycles';
            app.SaveeveryEditField_2Label = uilabel(app.MeasurementModeTab);
            app.SaveeveryEditField_2Label.Text = 'Save every';
            app.SaveeveryEditField_2 = uieditfield(app.MeasurementModeTab, 'numeric');
            app.SaveeveryEditField_2.Value = 2;
            app.minutesLabel = uilabel(app.MeasurementModeTab);
            app.minutesLabel.Text = 'minutes';
            app.LimittheButtonGroup = uibuttongroup(app.MeasurementModeTab);
            app.LimittheButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LimittheButtonGroupSelectionChanged, true);
            app.LimittheButtonGroup.Title = 'Limit the...';
            app.totalcyclesButton = uiradiobutton(app.LimittheButtonGroup);
            app.totalcyclesButton.Text = 'total cycles';
            app.totalcyclesButton.Value = true;
            app.totaltimeButton = uiradiobutton(app.LimittheButtonGroup);
            app.totaltimeButton.Text = 'total time';
            app.bothButton = uiradiobutton(app.LimittheButtonGroup);
            app.bothButton.Text = 'both';
            app.StopConditionTab = uitab(app.TabGroup2);
            app.StopConditionTab.Title = 'Stop Condition';
            app.ofoccurancesoutsideoflimitCheckBox = uicheckbox(app.StopConditionTab);
            app.ofoccurancesoutsideoflimitCheckBox.Text = '% of occurances outside of limit';
            app.NumberofoccurancesoutsideoflimitCheckBox = uicheckbox(app.StopConditionTab);
            app.NumberofoccurancesoutsideoflimitCheckBox.Text = 'Number of occurances outside of limit';
            app.AmountoutsideoflimitCheckBox = uicheckbox(app.StopConditionTab);
            app.AmountoutsideoflimitCheckBox.Text = 'Amount outside of limit';
            app.Label = uilabel(app.StopConditionTab);
            app.Label.Text = '%';
            app.EditField_4 = uieditfield(app.StopConditionTab, 'numeric');
            app.EditField_4Label_2 = uilabel(app.StopConditionTab);
            app.EditField_4Label_2.Text = 'times';
            app.EditField_5 = uieditfield(app.StopConditionTab, 'numeric');
            app.EditField_6 = uieditfield(app.StopConditionTab, 'numeric');
            app.DropDown = uidropdown(app.StopConditionTab);
            app.DropDown.Items = {'%', 'nm'};
            app.DropDown.Editable = 'on';
            app.DropDown.Value = '%';
            app.TransmittanceCheckBox = uicheckbox(app.StopConditionTab);
            app.TransmittanceCheckBox.Text = 'Transmittance';
            app.CenterWavelengthCheckBox = uicheckbox(app.StopConditionTab);
            app.CenterWavelengthCheckBox.Text = 'Center Wavelength';
            app.FWHMCheckBox = uicheckbox(app.StopConditionTab);
            app.FWHMCheckBox.Visible = 'off';
            app.FWHMCheckBox.Text = 'FWHM';
            app.StopbasedonLabel = uilabel(app.StopConditionTab);
            app.StopbasedonLabel.Text = 'Stop based on....';
            app.UpperLimitEditFieldLabel = uilabel(app.StopConditionTab);
            app.UpperLimitEditFieldLabel.Text = 'Upper Limit';
            app.UpperLimitEditField = uieditfield(app.StopConditionTab, 'numeric');
            app.LowerLimitEditFieldLabel = uilabel(app.StopConditionTab);
            app.LowerLimitEditFieldLabel.Text = 'Lower Limit';
            app.LowerLimitEditField = uieditfield(app.StopConditionTab, 'numeric');
            app.LimitLabel = uilabel(app.StopConditionTab);
            app.LimitLabel.Text = 'Limit';
            app.StatisticsTab = uitab(app.TabGroup2);
            app.StatisticsTab.Title = 'Statistics';
            app.GridLayout2_2 = uigridlayout(app.StatisticsTab);
            app.AmorphousTransmittanceLabel = uilabel(app.GridLayout2_2);
            app.AmorphousTransmittanceLabel.Text = 'Amorphous Transmittance';
            app.CrystallineTransmittanceLabel = uilabel(app.GridLayout2_2);
            app.CrystallineTransmittanceLabel.Text = 'Crystalline Transmittance';
            app.EditField_34 = uieditfield(app.GridLayout2_2, 'numeric');
            app.EditField_36 = uieditfield(app.GridLayout2_2, 'numeric');
            app.EditField_37 = uieditfield(app.GridLayout2_2, 'numeric');
            app.EditField_38 = uieditfield(app.GridLayout2_2, 'numeric');
            app.EditField_39 = uieditfield(app.GridLayout2_2, 'numeric');
            app.EditField_40 = uieditfield(app.GridLayout2_2, 'numeric');
            app.EditField_42 = uieditfield(app.GridLayout2_2, 'numeric');
            app.EditField_43 = uieditfield(app.GridLayout2_2, 'numeric');
            app.EditField_44 = uieditfield(app.GridLayout2_2, 'numeric');
            app.EditField_45 = uieditfield(app.GridLayout2_2, 'numeric');
            app.MeasuredLabel_2 = uilabel(app.GridLayout2_2);
            app.MeasuredLabel_2.Text = 'Measured';
            app.AverageLabel_3 = uilabel(app.GridLayout2_2);
            app.AverageLabel_3.Text = 'Average';
            app.AverageLabel_4 = uilabel(app.GridLayout2_2);
            app.AverageLabel_4.Text = 'Average';
            app.DeviationLabel_2 = uilabel(app.GridLayout2_2);
            app.DeviationLabel_2.Text = 'Deviation';
            app.MaximumLabel = uilabel(app.GridLayout2_2);
            app.MaximumLabel.Text = 'Maximum';
            app.MinimumLabel = uilabel(app.GridLayout2_2);
            app.MinimumLabel.Text = 'Minimum';
            app.AmorphousCWLLabel = uilabel(app.GridLayout2_2);
            app.AmorphousCWLLabel.Text = 'Amorphous CWL';
            app.CrystallineCWLLabel = uilabel(app.GridLayout2_2);
            app.CrystallineCWLLabel.Text = 'Crystalline CWL';
            app.EditField_54Label = uilabel(app.GridLayout2_2);
            app.EditField_54Label.Text = 'Edit Field';
            app.EditField_54 = uieditfield(app.GridLayout2_2, 'numeric');
            app.EditField2 = uieditfield(app.GridLayout2_2, 'numeric');
            app.EditField2_2 = uieditfield(app.GridLayout2_2, 'numeric');
            app.EditField2_3 = uieditfield(app.GridLayout2_2, 'numeric');
            app.EditField2_4Label = uilabel(app.GridLayout2_2);
            app.EditField2_4Label.Text = 'Edit Field2';
            app.EditField2_4 = uieditfield(app.GridLayout2_2, 'numeric');
            app.EditField3 = uieditfield(app.GridLayout2_2, 'numeric');
            app.EditField3_2 = uieditfield(app.GridLayout2_2, 'numeric');
            app.EditField3_3 = uieditfield(app.GridLayout2_2, 'numeric');
            app.TestingTab = uitab(app.TabGroup2);
            app.TestingTab.Title = 'Testing';
            app.USBRelayPanel = uipanel(app.TestingTab);
            app.USBRelayPanel.Title = 'USB Relay';
            app.Switch = uiswitch(app.USBRelayPanel, 'toggle');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.CurrentValuesPanel = uipanel(app.TestingTab);
            app.CurrentValuesPanel.Title = 'Current Values';
            app.TransmittanceLabel = uilabel(app.CurrentValuesPanel);
            app.TransmittanceLabel.Text = 'Transmittance (%)';
            app.TransmittanceEditField = uieditfield(app.CurrentValuesPanel, 'numeric');
            app.CenterWavelengthmEditFieldLabel = uilabel(app.CurrentValuesPanel);
            app.CenterWavelengthmEditFieldLabel.Text = 'Center Wavelength (µm)';
            app.CenterWavelengthmEditField = uieditfield(app.CurrentValuesPanel, 'numeric');
            app.FWHMnmEditFieldLabel = uilabel(app.CurrentValuesPanel);
            app.FWHMnmEditFieldLabel.Text = 'FWHM (nm)';
            app.FWHMnmEditField = uieditfield(app.CurrentValuesPanel, 'numeric');
            app.SampleDataPanel = uipanel(app.CurrentValuesPanel);
            app.SampleDataPanel.Title = 'Sample Data';
            app.LoadIRImageButton = uibutton(app.SampleDataPanel, 'push');
            app.LoadIRImageButton.Text = 'Load IR Image';
            app.LoadFTIRdataButton = uibutton(app.SampleDataPanel, 'push');
            app.LoadFTIRdataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFTIRdataButtonPushed, true);
            app.LoadFTIRdataButton.Text = 'Load FTIR data';
            app.FilesTab = uitab(app.TabGroup2);
            app.FilesTab.Title = 'Files';
            app.FoldertoSaveFilesButton = uibutton(app.FilesTab, 'state');
            app.FoldertoSaveFilesButton.ValueChangedFcn = createCallbackFcn(app, @ChooseFileLocationButtonValueChanged, true);
            app.FoldertoSaveFilesButton.Text = 'Folder to Save Files';
            app.EditField_53 = uieditfield(app.FilesTab, 'text');
            app.EditField_53.ValueChangedFcn = createCallbackFcn(app, @ChooseFileLocationButtonValueChanged, true);
            app.EditField_53.Editable = 'off';
            app.FTIRDataFolderEditField = uieditfield(app.FilesTab, 'text');
            app.FTIRDataFolderEditField.Editable = 'off';
            app.FTIRFileNameEditField = uieditfield(app.FilesTab, 'text');
            app.FTIRFileNameEditField.Value = 'ftir_data';
            app.SaveddatafilenameEditFieldLabel = uilabel(app.FilesTab);
            app.SaveddatafilenameEditFieldLabel.Text = 'Saved data file name';
            app.SaveddatafilenameEditField = uieditfield(app.FilesTab, 'text');
            app.SaveddatafilenameEditField.Value = 'test_data';
            app.FoldertoLoadFilesButton = uibutton(app.FilesTab, 'state');
            app.FoldertoLoadFilesButton.ValueChangedFcn = createCallbackFcn(app, @FoldertoLoadFilesButtonValueChanged, true);
            app.FoldertoLoadFilesButton.Text = 'Folder to Load Files';
            app.FoldertoLoadFilesButton_2 = uibutton(app.FilesTab, 'state');
            app.FoldertoLoadFilesButton_2.ValueChangedFcn = createCallbackFcn(app, @FoldertoLoadFilesButton_2ValueChanged, true);
            app.FoldertoLoadFilesButton_2.Text = 'Folder to Load Files';
            app.IRCamDataFolderEditField = uieditfield(app.FilesTab, 'text');
            app.IRCamDataFolderEditField.Editable = 'off';
            app.IRCamFileNameEditField = uieditfield(app.FilesTab, 'text');
            app.IRCamFileNameEditField.Value = 'ir_image_data';
            app.SaveddatafilenameEditFieldLabel_2 = uilabel(app.FilesTab);
            app.SaveddatafilenameEditFieldLabel_2.Text = 'FTIR Data File Name';
            app.SaveddatafilenameEditFieldLabel_3 = uilabel(app.FilesTab);
            app.SaveddatafilenameEditFieldLabel_3.Text = 'IR Camera File Name';
            app.Switch_2 = uiswitch(app.LeftPanel, 'slider');
            app.Switch_2.Items = {'Stop', 'Start'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'Stop';
            app.DrawROIButton = uibutton(app.LeftPanel, 'push');
            app.DrawROIButton.ButtonPushedFcn = createCallbackFcn(app, @DrawROIButtonPushed, true);
            app.DrawROIButton.Text = 'Draw ROI ';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup3 = uitabgroup(app.RightPanel);
            app.TransmittanceTab = uitab(app.TabGroup3);
            app.TransmittanceTab.Title = 'Transmittance';
            app.UIAxes2 = uiaxes(app.TransmittanceTab);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, 'Number of Cycles')
            ylabel(app.UIAxes2, 'Transmittance (%)')
            app.UIAxes2.XScale = 'log';
            app.CenterWavelengthTab = uitab(app.TabGroup3);
            app.CenterWavelengthTab.Title = 'Center Wavelength';
            app.UIAxes3 = uiaxes(app.CenterWavelengthTab);
            title(app.UIAxes3, '')
            xlabel(app.UIAxes3, 'Number of Cycles')
            ylabel(app.UIAxes3, 'Center Wavelength (nm)')
            app.UIAxes3.XScale = 'log';
            app.FWHMTab = uitab(app.TabGroup3);
            app.FWHMTab.Title = 'FWHM';
            app.UIAxes5 = uiaxes(app.FWHMTab);
            title(app.UIAxes5, '')
            xlabel(app.UIAxes5, 'Cycle Count')
            ylabel(app.UIAxes5, 'FWHM (um)')
            app.UIAxes5.XScale = 'log';
            app.FTIRTab = uitab(app.TabGroup3);
            app.FTIRTab.Title = 'FTIR';
            app.UIAxes3_2 = uiaxes(app.FTIRTab);
            title(app.UIAxes3_2, '')
            xlabel(app.UIAxes3_2, 'Wavelength (µm)')
            ylabel(app.UIAxes3_2, 'Transmittance (%)')
            app.UIAxes3_2.XLim = [2.5 4];
            app.UIAxes3_2.YLim = [0 100];
            app.TabGroup4 = uitabgroup(app.RightPanel);
            app.LaserOscFGenTab = uitab(app.TabGroup4);
            app.LaserOscFGenTab.Title = 'Laser (Osc/FGen)';
            app.UIAxes = uiaxes(app.LaserOscFGenTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Voltage (V)')
            app.LiveWaveformPlotSwitchLabel = uilabel(app.LaserOscFGenTab);
            app.LiveWaveformPlotSwitchLabel.Text = 'Live Waveform Plot';
            app.LiveWaveformPlotSwitch = uiswitch(app.LaserOscFGenTab, 'slider');
            app.LiveWaveformPlotSwitch.ValueChangedFcn = createCallbackFcn(app, @LiveWaveformPlotSwitchValueChanged, true);
            app.RefreshPeriodsSpinnerLabel = uilabel(app.LaserOscFGenTab);
            app.RefreshPeriodsSpinnerLabel.Text = 'Refresh Period (s)';
            app.RefreshPeriodsSpinner = uispinner(app.LaserOscFGenTab);
            app.RefreshPeriodsSpinner.ValueChangedFcn = createCallbackFcn(app, @RefreshPeriodsSpinnerValueChanged, true);
            app.ReadWaveformButton = uibutton(app.LaserOscFGenTab, 'push');
            app.ReadWaveformButton.ButtonPushedFcn = createCallbackFcn(app, @ReadWaveformButtonPushed, true);
            app.ReadWaveformButton.Text = 'Read Waveform';
            app.ReadWaveformStatus = uilabel(app.LaserOscFGenTab);
            app.ReadWaveformStatus.Text = '';
            app.IRImageTab = uitab(app.TabGroup4);
            app.IRImageTab.Title = 'IR Image';
            app.UIAxes4 = uiaxes(app.IRImageTab);
            title(app.UIAxes4, '')
            xlabel(app.UIAxes4, '')
            ylabel(app.UIAxes4, '')
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end